classdef OpticalTrapGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        Power1SliderLabel      matlab.ui.control.Label
        Power1Slider           matlab.ui.control.Slider
        Power2SliderLabel      matlab.ui.control.Label
        Power2Slider           matlab.ui.control.Slider
        Waist1SliderLabel      matlab.ui.control.Label
        Waist1Slider           matlab.ui.control.Slider
        Waist2SliderLabel      matlab.ui.control.Label
        Waist2Slider           matlab.ui.control.Slider
        xEditFieldLabel        matlab.ui.control.Label
        xEditField             matlab.ui.control.EditField
        yEditFieldLabel        matlab.ui.control.Label
        yEditField             matlab.ui.control.EditField
        zEditFieldLabel        matlab.ui.control.Label
        zEditField             matlab.ui.control.EditField
        PlotOptionButtonGroup  matlab.ui.container.ButtonGroup
        xButton                matlab.ui.control.RadioButton
        yButton                matlab.ui.control.RadioButton
        zButton                matlab.ui.control.RadioButton
        P2                     matlab.ui.control.NumericEditField
        w1                     matlab.ui.control.NumericEditField
        w2                     matlab.ui.control.NumericEditField
        P1                     matlab.ui.control.NumericEditField
        DepthuKLabel           matlab.ui.control.Label
        DepthuKEditField       matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel   matlab.ui.control.Label
        FreqHzEditField        matlab.ui.control.NumericEditField
        r1EditFieldLabel       matlab.ui.control.Label
        r1EditField            matlab.ui.control.EditField
        r2EditFieldLabel       matlab.ui.control.Label
        r2EditField            matlab.ui.control.EditField
        coordEditFieldLabel    matlab.ui.control.Label
        coordEditField         matlab.ui.control.EditField
    end

    
    methods (Access = public)
        
        function results = updateplot(app)
            P1 = app.Power1Slider.Value;
            P2 = app.Power2Slider.Value;
            w1 = app.Waist1Slider.Value*1e-6;
            w2 = app.Waist2Slider.Value*1e-6;
            
            th = 22.5/2;
            scale = 2e-36;
            
            r1 = sscanf(app.r1EditField.Value,'%f,%f,%f');
            r2 = sscanf(app.r2EditField.Value,'%f,%f,%f');
            
            L1 = laserdata(scale,P1,w1,r1*1e-6,th,0);
            L2 = laserdata(scale,P2,w2,r2*1e-6,-th,0);
            
%             [x,y,z] = app.expand;
            rc = sscanf(app.coordEditField.Value,'%f,%f');
            c = 1e-6*linspace(min(rc),max(rc),1e3);
            switch app.PlotOptionButtonGroup.SelectedObject
                case app.xButton
                    x = c;
                    y = 0;
                    z = 0;
                case app.yButton
                    x = 0;
                    y = c;
                    z = 0;
                case app.zButton
                    x = 0;
                    y = 0;
                    z = c;
            end
            
            U1 = L1.potential(x,y,z);
            U2 = L2.potential(x,y,z);
            
            U = U1 + U2 + const.mRb*9.8*z;
            
%             switch app.PlotOptionButtonGroup.SelectedObject
%                 case app.xButton
%                     c = x;
%                 case app.yButton
%                     c = y;
%                 case app.zButton
%                     c = z;
%             end
            
            plot(app.UIAxes,c*1e6,U/(const.kb*1e-6),'.-');
            xlabel(app.UIAxes,'Position [um]');
            ylabel(app.UIAxes,'Potential energy [uK]');
            pause(1e-3);
            
            r0 = app.findroots(c,U);
            if numel(r0) == 1
                depth = max(U) - U(r0);
            elseif numel(r0) == 2 && abs(diff(r0)) == 1
                depth = max(U) - U(r0(1));
            elseif numel(r0) == 2
                depth = abs(diff(U(r0)));
            else
                depth = 0;
            end
            app.DepthuKEditField.Value = depth/const.kb*1e6;
            
            if depth > 0
                Udd = diff(U,2)/(c(2) - c(1)).^2;
                if abs(diff(r0)) == 1
                    r0 = r0(1);
                end
                Udd = Udd(r0);
                Udd = Udd(Udd > 0);
                freq = sqrt(Udd/const.mRb)/(2*pi);
                app.FreqHzEditField.Value = freq;
            else
                app.FreqHzEditField.Value = 0;
            end
        end
        
        function x0 = findroots(app,x,U)
            Ud = diff(U,1);
            u0 = Ud(1);
            x0 = [];
            mm = 0;
            for nn = 2:numel(Ud)
                u1 = Ud(nn);
                if sign(u0) ~= sign(u1)
                    mm = mm + 1;
                    x0(mm) = nn;
                end
                u0 = u1;
            end
        end
    end
    
    methods (Access = private)
        
        function [x,y,z] = expand(app)
            rx = sscanf(app.xEditField.Value,'%f,%f');
            ry = sscanf(app.yEditField.Value,'%f,%f');
            rz = sscanf(app.zEditField.Value,'%f,%f');
            N = 1e3;
            x = 1e-6*linspace(min(rx),max(rx),N);
            y = 1e-6*linspace(min(ry),max(ry),N);
            z = 1e-6*linspace(min(rz),max(rz),N);
        end
        
        function c = getcoordinate(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateplot;
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.updateplot;
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            value = app.zEditField.Value;
            app.updateplot;
        end

        % Selection changed function: PlotOptionButtonGroup
        function PlotOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotOptionButtonGroup.SelectedObject;
            app.updateplot;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            app.updateplot;
        end

        % Value changed function: Power1Slider
        function Power1SliderValueChanged(app, event)
            value = app.Power1Slider.Value;
            app.updateplot;
            app.P1.Value = value;
        end

        % Value changed function: Power2Slider
        function Power2SliderValueChanged(app, event)
            value = app.Power2Slider.Value;
            app.updateplot;
            app.P2.Value = value;
        end

        % Value changed function: Waist1Slider
        function Waist1SliderValueChanged(app, event)
            value = app.Waist1Slider.Value;
            app.updateplot;
            app.w1.Value = value;
        end

        % Value changed function: Waist2Slider
        function Waist2SliderValueChanged(app, event)
            value = app.Waist2Slider.Value;
            app.updateplot;
            app.w2.Value = value;
        end

        % Value changed function: P1
        function P1ValueChanged(app, event)
            value = app.P1.Value;    
            app.Power1Slider.Value = value;
            app.updateplot;
        end

        % Value changed function: P2
        function P2ValueChanged(app, event)
            value = app.P2.Value;
            app.Power2Slider.Value = value;
            app.updateplot;
        end

        % Value changed function: w1
        function w1ValueChanged(app, event)
            value = app.w1.Value;
            app.Waist1Slider.Value = value;
            app.updateplot;
        end

        % Value changed function: w2
        function w2ValueChanged(app, event)
            value = app.w2.Value;
            app.Waist2Slider.Value = value;
            app.updateplot;
        end

        % Value changed function: r1EditField
        function r1EditFieldValueChanged(app, event)
            value = app.r1EditField.Value;
            app.updateplot;
        end

        % Value changed function: r2EditField
        function r2EditFieldValueChanged(app, event)
            value = app.r2EditField.Value;
            app.updateplot;
        end

        % Value changed function: coordEditField
        function coordEditFieldValueChanged(app, event)
            value = app.coordEditField.Value;
            app.updateplot;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Power1SliderLabel = uilabel(app.UIFigure);
            app.Power1SliderLabel.Text = 'Power1';
            app.Power1Slider = uislider(app.UIFigure);
            app.Power1Slider.Limits = [0 50];
            app.Power1Slider.ValueChangedFcn = createCallbackFcn(app, @Power1SliderValueChanged, true);
            app.Power1Slider.Value = 15;
            app.Power2SliderLabel = uilabel(app.UIFigure);
            app.Power2SliderLabel.Text = 'Power2';
            app.Power2Slider = uislider(app.UIFigure);
            app.Power2Slider.Limits = [0 50];
            app.Power2Slider.ValueChangedFcn = createCallbackFcn(app, @Power2SliderValueChanged, true);
            app.Power2Slider.Value = 50;
            app.Waist1SliderLabel = uilabel(app.UIFigure);
            app.Waist1SliderLabel.Text = 'Waist1';
            app.Waist1Slider = uislider(app.UIFigure);
            app.Waist1Slider.Limits = [0 500];
            app.Waist1Slider.ValueChangedFcn = createCallbackFcn(app, @Waist1SliderValueChanged, true);
            app.Waist1Slider.Value = 130;
            app.Waist2SliderLabel = uilabel(app.UIFigure);
            app.Waist2SliderLabel.Text = 'Waist2';
            app.Waist2Slider = uislider(app.UIFigure);
            app.Waist2Slider.Limits = [0 500];
            app.Waist2Slider.ValueChangedFcn = createCallbackFcn(app, @Waist2SliderValueChanged, true);
            app.Waist2Slider.Value = 130;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = '0';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.yEditField.Value = '0';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Value = '-1000,1000';
            app.PlotOptionButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotOptionButtonGroupSelectionChanged, true);
            app.PlotOptionButtonGroup.Title = 'Plot Option';
            app.xButton = uiradiobutton(app.PlotOptionButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.PlotOptionButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.PlotOptionButtonGroup);
            app.zButton.Text = 'z';
            app.P1 = uieditfield(app.UIFigure, 'numeric');
            app.P1.ValueDisplayFormat = '%0.1f';
            app.P1.ValueChangedFcn = createCallbackFcn(app, @P1ValueChanged, true);
            app.P1.Value = 15;
            app.P2 = uieditfield(app.UIFigure, 'numeric');
            app.P2.ValueDisplayFormat = '%0.1f';
            app.P2.ValueChangedFcn = createCallbackFcn(app, @P2ValueChanged, true);
            app.P2.Value = 50;
            app.w1 = uieditfield(app.UIFigure, 'numeric');
            app.w1.ValueDisplayFormat = '%0.1f';
            app.w1.ValueChangedFcn = createCallbackFcn(app, @w1ValueChanged, true);
            app.w1.Value = 130;
            app.w2 = uieditfield(app.UIFigure, 'numeric');
            app.w2.ValueDisplayFormat = '%0.1f';
            app.w2.ValueChangedFcn = createCallbackFcn(app, @w2ValueChanged, true);
            app.w2.Value = 130;
            app.DepthuKLabel = uilabel(app.UIFigure);
            app.DepthuKLabel.Text = 'Depth [uK]';
            app.DepthuKEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthuKEditField.ValueDisplayFormat = '%0.3f';
            app.FreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.FreqHzEditFieldLabel.Text = 'Freq [Hz]';
            app.FreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqHzEditField.ValueDisplayFormat = '%0.3f';
            app.r1EditFieldLabel = uilabel(app.UIFigure);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.UIFigure, 'text');
            app.r1EditField.ValueChangedFcn = createCallbackFcn(app, @r1EditFieldValueChanged, true);
            app.r1EditField.Value = '0,0,0';
            app.r2EditFieldLabel = uilabel(app.UIFigure);
            app.r2EditFieldLabel.Text = 'r2';
            app.r2EditField = uieditfield(app.UIFigure, 'text');
            app.r2EditField.ValueChangedFcn = createCallbackFcn(app, @r2EditFieldValueChanged, true);
            app.r2EditField.Value = '0,0,0';
            app.coordEditFieldLabel = uilabel(app.UIFigure);
            app.coordEditFieldLabel.Text = 'coord';
            app.coordEditField = uieditfield(app.UIFigure, 'text');
            app.coordEditField.ValueChangedFcn = createCallbackFcn(app, @coordEditFieldValueChanged, true);
            app.coordEditField.Value = '-1000,1000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpticalTrapGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end